classdef app_one < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BrowsefolderButton  matlab.ui.control.Button
        Image               matlab.ui.control.Image
        PreviousButton      matlab.ui.control.Button
        NextButton          matlab.ui.control.Button
    end

    properties (Access = private)
        img_index = 1;
        filenames = '';
    end
    
    methods (Access = private)

        % Image format converstion: uint16, grayscale -> uint8, RGB
        function img_formatted = reformat_img(app, img)
           
            img_uint8 = im2uint8(img);
            
            % if grayscale
            if size(img_uint8, 3) == 1
                img_formatted = cat(3, img_uint8, img_uint8, img_uint8); 

            else
                img_formatted = img_uint8;

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowsefolderButton
        function BrowsefolderButtonPushed(app, event)
            
            folder_path = uigetdir('C:\Users\Kasutaja\Desktop\BAKATÖÖ');
            app.filenames = dir(fullfile(folder_path, '*.tif'));
            nr_images = numel(app.filenames);
            
            % 'F2_03_1_1Z0_Bright Field_002.tif';
            % <WellID>_<Read index>_<Channel index>_<Z index>_<Channel name>_<cycle index>.tif
            
            pattern = '(?<WellID>\w+)_(?<ReadIndex>\d+)_(?<ChannelIndex>\d+)_(?<ZIndex>\w+)_(?<ChannelName>[\w\s]+)_(?<CycleIndex>\d+)';
            filenames_data = struct('WellID', {}, 'ReadIndex', {}, 'ChannelIndex', {}, 'ZIndex', {}, 'ChannelName', {}, 'CycleIndex', {});
            
            % Read in file name data into table filenames_data
            for n = 1:nr_images
                f = app.filenames(n).name;
                file_data = regexp(f, pattern, 'names');
                filenames_data(n) = file_data;
            end
            
            image1 = imread(app.filenames(app.img_index).name);
            img_formatted = app.reformat_img(image1);

            app.Image.ImageSource = img_formatted;

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Load next image
            % NB! gets stuck on img 3?

            app.img_index = app.img_index + 1;

            image_next = imread(app.filenames(app.img_index).name);
            img_formatted = app.reformat_img(image_next);

            app.Image.ImageSource = img_formatted;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = ['MATLAB' newline 'App'];
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.Text = 'Previous';
            app.Image = uiimage(app.UIFigure);
            app.BrowsefolderButton = uibutton(app.UIFigure, 'push');
            app.BrowsefolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsefolderButtonPushed, true);
            app.BrowsefolderButton.Text = ['Browse' newline 'folder'];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_one

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end